classdef MatlabProFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Play               matlab.ui.container.Menu
        Premade            matlab.ui.container.Menu
        UserCreated        matlab.ui.container.Menu
        Toolbar            matlab.ui.container.Toolbar
        SixteenthNote      matlab.ui.container.toolbar.PushTool
        EigthNote          matlab.ui.container.toolbar.PushTool
        QuarterNote        matlab.ui.container.toolbar.PushTool
        HalfNote           matlab.ui.container.toolbar.PushTool
        DottedQuarterNote  matlab.ui.container.toolbar.PushTool
        DottedHalfNote     matlab.ui.container.toolbar.PushTool
        Whole              matlab.ui.container.toolbar.PushTool
        IsSharp            matlab.ui.container.toolbar.PushTool
        IsFlat             matlab.ui.container.toolbar.PushTool
        IsRest             matlab.ui.container.toolbar.PushTool
        TempoSliderLabel   matlab.ui.control.Label
        TempoSlider        matlab.ui.control.Slider
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        playUsercreated %1 for true and 0 for false
        NotePic = 1;
        RestPic = 1;
        Rest = 0;%True if rest false if not
        NewMeasureCounter = 0; %counts the notes if there is a new measure then draws it
        
        Flat = 0;
        Sharp = 0;
        
        ImageSharp
        ImageSharpAlpha
        ImageFlat
        ImageFlatAlpha
        
        
        SongTempo = 60;
        SongNoteFreq = []; %Used for the user created
        SongNoteOct = [];
        SongNoteLengths = [];
        Counter = 1;
        
        
        NotePlacementX = 120 %once a note has been clicked this determiens were it will appear
        NotePlacementY %once a note has been clicked this determines were it will appear
        TopLine = 100; %very important because the new lines are all created with respect to the top line

        
        NoteType %For user created passed into SongNoteFreq
        NoteOct %For User Created passed into songNote Oct
        NoteLength %For user Created passed into SongNoteLengths
        NotePreview %Blue spot that hovers over a point which when clicked enteres that note, Is constantly deleted and redraw too keep up to date
    end
    
    methods (Access = public)
        
        function [] = main(app)
            
                if(app.playUsercreated)
                    Play_Music(app,app.SongTempo,app.SongNoteFreq,app.SongNoteOct,app.SongNoteLengths);
                else
                    [tempo, notefreq, oct, notelength] = PremadeSongs(app);
                    Play_Music(app,tempo,notefreq,oct,notelength);
                end
        end
        
        function [] = Play_Music(~,Tempo,Note,Oct,NoteLen)
            a = arduino();
            Q = (Tempo/60)^-1;
            DQ = Q*1.5;
            E = Q/2;
            S = Q/4;
            H = Q*2;
            DH = H*1.5;
            W = Q*4;
            ListOfLengths = [S,E,Q,H,DH,W,DQ];
            
            Notes = [16.35 17.32 18.35 19.45 20.60 21.83 23.12 24.50 25.96 27.50 29.14 30.87 0];

            Octave = [1;2;4;8.000611621;16.00183346;32;64;128;256]; %not exactly 8 and 16 because Im trying to keep the notes in tune
            MatrixOfNotes = Notes.*Octave; %Obligitory matrix is right here
            
            ActualFreq = [];
            for i = 1:length(Note)
                ActualFreq(i) = MatrixOfNotes(Oct(i),Note(i));
            end
            
            tic
            for notenum = 1:length(Note)
                playTone(a,'D5',ActualFreq(notenum),ListOfLengths(NoteLen(notenum)));
                pause(ListOfLengths(NoteLen(notenum)));
            end
            toc
        end

        function [SongTempo,SongNotes,SongOct,SongNoteLengths] = PremadeSongs(~)
            song = menu('Choose premade song','Spain: by Chick Corea','testing');
            
            if(song == 1)
                SongTempo = 100;
                SongNotes = [3 1 3  13  3 5 8 6 5 3  5 3 1  13 1 3 5 3 1 11 1 11 1 3   8 10 11 10   1 12 11 10    8 6 5  6 8 10  8 6 5  6 5 6 8    13    6 5 5   3 1 3];
                SongOct = [5 5 5  5  5 5 5 5 5 5   5 5 5   5 5 5 5 5 5    4 5 4 5 5    4 4 4 4    5 4 4 4     4 4 4   4 4 4   4 4 4  4 4 4 4    4    4 4 4   4 4 4];
                SongNoteLengths = [1 1 6  2  2 2 2 2 2 2  1 1 6  2 2 2 2 2 3 1 1 1 1 6    1 1 2 2    2 1 1 3   1 1 7   1 1 6  7 2 7   1 1 1 6    4    2 2 7   1 1 6];
            end
            
            if(song == 2)
                SongTempo = 20;
                SongNotes = [3 3];
                SongOct = [5 5];
                SongNoteLengths = [6 6];
            end

        end
        
        function [] = NewMeasure(app)
            Q = (app.SongTempo/60)^-1;
            DQ = Q*1.5;
            E = Q/2;
            S = Q/4;
            H = Q*2;
            DH = H*1.5;
            W = Q*4;
            ListOfLengths = [S,E,Q,H,DH,W,DQ];
            
            app.NewMeasureCounter = app.NewMeasureCounter + ListOfLengths(app.SongNoteLengths(app.Counter));
 
            if(app.NewMeasureCounter >= W)
                plot(app.UIAxes,[app.NotePlacementX,app.NotePlacementX],[app.TopLine,app.TopLine+64],'k')
                app.NewMeasureCounter = 0;
                app.NotePlacementX = app.NotePlacementX + 50;
                
            end
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            [app.ImageSharp,~,app.ImageSharpAlpha] = imread('sharp.png');
            [app.ImageFlat,~,app.ImageFlatAlpha] = imread('flat.png');
            
            app.UIAxes.XLim = [0,1000];
            app.UIAxes.YLim = [0,1000];
            imshow('background3.png', 'Parent', app.UIAxes,'XData', [0 1000],'YData', [0 1000]);
            hold(app.UIAxes,"on"); %So stuff can be plotted in the same place stays on for the rest of the program
            
            plot(app.UIAxes,[100,900],[100,100],'k')
            plot(app.UIAxes,[100,900],[116,116],'k')
            plot(app.UIAxes,[100,900],[132,132],'k')
            plot(app.UIAxes,[100,900],[148,148],'k')
            plot(app.UIAxes,[100,900],[164,164],'k')
        end

        % Menu selected function: Premade
        function PremadeMenuSelected(app, event)
            app.playUsercreated = 0;
            main(app)
        end

        % Menu selected function: UserCreated
        function UserCreatedMenuSelected(app, event)
            app.playUsercreated = 1;
            main(app)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

            if(app.NotePic == 1)
                disp('Please Select a Note Before writing the song')
            else
                if(app.Rest)
                    app.NoteType = 13;
                    [image,~,alpha] = imread(app.RestPic);
                    I3 = imshow(image,'Parent',app.UIAxes,'XData',[app.NotePlacementX-10,app.NotePlacementX+10],'YData',[app.TopLine+16,app.TopLine+47]);
                    set(I3,'AlphaData',alpha);
                elseif(app.Sharp)
                    if((app.NoteType == 5) || (app.NoteType == 12))
                        disp('no B sharp or E sharp')
                    else
                        
                        I2 = imshow(app.ImageSharp,'Parent',app.UIAxes,'XData',[app.NotePlacementX-15,app.NotePlacementX-4],'YData',[app.NotePlacementY-15,app.NotePlacementY+2.5]);
                        set(I2,'AlphaData',app.ImageSharpAlpha)
                        app.NoteType = app.NoteType + 1;
                    end
                elseif(app.Flat)
                    if((app.NoteType == 1)||(app.NoteType == 6))
                        disp('no C flat or F flat')
                    else
                        
                        I2 = imshow(app.ImageFlat,'Parent',app.UIAxes,'XData',[app.NotePlacementX-15,app.NotePlacementX-4],'YData',[app.NotePlacementY-15,app.NotePlacementY+2.5]);
                        set(I2,'AlphaData',app.ImageFlatAlpha)
                        app.NoteType = app.NoteType - 1;
                    end
                end
                
                if(~(app.Rest))
                    [image, ~, alpha] = imread(app.NotePic);
                    I = imshow(image,'Parent',app.UIAxes,'XData',[app.NotePlacementX-6,app.NotePlacementX+16],'YData',[app.NotePlacementY-30,app.NotePlacementY+5]);
                    set(I,'AlphaData',alpha);
                end
                
                app.SongNoteFreq(app.Counter) = app.NoteType;
                app.SongNoteOct(app.Counter) = app.NoteOct;
                app.SongNoteLengths(app.Counter) = app.NoteLength;
                %app.Counter = app.Counter + 1;
                app.NotePlacementX = app.NotePlacementX + 50;
                
                NewMeasure(app);
                app.Counter = app.Counter + 1;
                
                if(app.NotePlacementX >= 820)
                    app.NotePlacementX = 120;
                    app.TopLine = app.TopLine + 300;
                    
                    plot(app.UIAxes,[100,900],[app.TopLine,app.TopLine],'k')
                    plot(app.UIAxes,[100,900],[app.TopLine+16,app.TopLine+16],'k')
                    plot(app.UIAxes,[100,900],[app.TopLine+32,app.TopLine+32],'k')
                    plot(app.UIAxes,[100,900],[app.TopLine+48,app.TopLine+48],'k')
                    plot(app.UIAxes,[100,900],[app.TopLine+64,app.TopLine+64],'k')
                end
            end
            
        end

        % Clicked callback: SixteenthNote
        function SixteenthNoteClicked(app, event)
            app.NotePic = '16th.png';
            app.NoteLength = 1;
            app.RestPic = 'sixteenthRest.png';
        end

        % Clicked callback: EigthNote
        function EigthNoteClicked(app, event)
            app.NotePic = '8th.png';
            app.NoteLength = 2;
            app.RestPic = 'eigthRest.png';
        end

        % Clicked callback: QuarterNote
        function QuarterNoteClicked(app, event)
            app.NotePic = 'quarter.png';
            app.NoteLength = 3;
            app.RestPic = 'quarterRest.png';
        end

        % Clicked callback: HalfNote
        function HalfNoteClicked(app, event)
            app.NotePic = 'half.png';
            app.NoteLength = 4;
            app.RestPic = 'halfRest.png';
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            points = app.UIAxes.CurrentPoint(1,:);
            x = points(1);
            y = points(2);
            
            if(y > app.TopLine-36 && y < app.TopLine-28)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine-32,'b*');
                app.NoteType = 1; %C
                app.NoteOct = 6;
                app.NotePlacementY = app.TopLine-32;
            end
                        
            if(y > app.TopLine-28 && y < app.TopLine-20)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine-24,'b*');
                app.NoteType = 12; %B
                app.NoteOct = 5;
                app.NotePlacementY = app.TopLine-24;
            end
            
            if(y > app.TopLine-20 && y < app.TopLine-12)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine-16,'b*');
                app.NoteType = 10; %A
                app.NoteOct = 5;
                app.NotePlacementY = app.TopLine-16;
            end
            
            if(y > app.TopLine-12 && y < app.TopLine-4)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine-8,'b*');
                app.NoteType = 8; %G
                app.NoteOct = 5;
                app.NotePlacementY = app.TopLine-8;
            end
            
            if(y > app.TopLine-4 && y < app.TopLine+4)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine,'b*');
                app.NoteType = 6; %F
                app.NoteOct = 5;
                app.NotePlacementY = app.TopLine;
            end
            
            if(y > app.TopLine+4 && y < app.TopLine+12)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+8,'b*');
                app.NoteType = 5; %E
                app.NoteOct = 5;
                app.NotePlacementY = app.TopLine+8;
            end
            
            if(y > app.TopLine+12 && y < app.TopLine+20)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+16,'b*');
                app.NoteType = 3; %D
                app.NoteOct = 5;
                app.NotePlacementY = app.TopLine+16;
            end
            
            if(y > app.TopLine+20 && y < app.TopLine+28)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+24,'b*');
                app.NoteType = 1; %C
                app.NoteOct = 5;
                app.NotePlacementY = app.TopLine+24;
            end
            
            if(y > app.TopLine+28 && y < app.TopLine+36)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+32,'b*');
                app.NoteType = 12; %B
                app.NoteOct = 4;
                app.NotePlacementY = app.TopLine+32;
            end
            
            if(y > app.TopLine+36 && y < app.TopLine+44)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+40,'b*');
                app.NoteType = 10; %A
                app.NoteOct = 4;
                app.NotePlacementY = app.TopLine+40;
            end
            
            if(y > app.TopLine+44 && y < app.TopLine+52)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+48,'b*');
                app.NoteType = 8; %G
                app.NoteOct = 4;
                app.NotePlacementY = app.TopLine+48;
            end
            
            if(y > app.TopLine+52 && y < app.TopLine+60)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+56,'b*');
                app.NoteType = 6; %F
                app.NoteOct = 4;
                app.NotePlacementY = app.TopLine+56;
            end
            
            if(y > app.TopLine+60 && y < app.TopLine+68)
                delete(app.NotePreview)
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+64,'b*');
                app.NoteType = 5; %E
                app.NoteOct = 4;
                app.NotePlacementY = app.TopLine+64;
            end
            
            if(y > app.TopLine+68 && y < app.TopLine+76)
                delete(app.NotePreview)
                app.NoteType = 3; %D
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+72,'b*');
                app.NoteOct = 4;
                app.NotePlacementY = app.TopLine+72;
            end
            
            if(y > app.TopLine+76 && y < app.TopLine+84)
                delete(app.NotePreview)
                app.NoteType = 1; %C
                app.NotePreview = plot(app.UIAxes,app.NotePlacementX,app.TopLine+80,'b*');
                app.NoteOct = 4;
                app.NotePlacementY = app.TopLine+80;
            end
        end

        % Clicked callback: DottedQuarterNote
        function DottedQuarterNoteClicked(app, event)
            app.NotePic = 'DottedQ.png';
            app.NoteLength = 7;
            app.RestPic = 'DottedQuarterRest.png';
        end

        % Clicked callback: DottedHalfNote
        function DottedHalfNoteClicked(app, event)
            app.NotePic = 'DH.png';
            app.NoteLength = 5;
            app.RestPic = 'DottedHalfRest.png';
        end

        % Clicked callback: Whole
        function WholeClicked(app, event)
            app.NotePic = 'WholeNote.png';
            app.NoteLength = 6;
            app.RestPic = 'wholeRest.png';
        end

        % Value changed function: TempoSlider
        function TempoSliderValueChanged(app, event)
            app.SongTempo = app.TempoSlider.Value;
        end

        % Clicked callback: IsFlat
        function IsFlatClicked(app, event)
            app.Sharp = 0;
            app.Rest = 0;
            if(~(app.Flat))
                app.Flat = 1;
            else
                app.Flat = 0;
            end
        end

        % Clicked callback: IsSharp
        function IsSharpClicked(app, event)
            app.Flat = 0;
            app.Rest = 0;
            if(~(app.Sharp))
                app.Sharp = 1;
            else
                app.Sharp = 0;
            end
        end

        % Clicked callback: IsRest
        function IsRestClicked(app, event)
            app.Flat = 0;
            app.Sharp = 0;
            if(~(app.Rest))
                app.Rest = 1;
            else
                app.Rest = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.Play = uimenu(app.UIFigure);
            app.Play.Text = 'Play';
            app.Premade = uimenu(app.Play);
            app.Premade.MenuSelectedFcn = createCallbackFcn(app, @PremadeMenuSelected, true);
            app.Premade.Text = 'PremadeSong';
            app.UserCreated = uimenu(app.Play);
            app.UserCreated.MenuSelectedFcn = createCallbackFcn(app, @UserCreatedMenuSelected, true);
            app.UserCreated.Text = 'UserCreatedSong';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.SixteenthNote = uipushtool(app.Toolbar);
            app.SixteenthNote.ClickedCallback = createCallbackFcn(app, @SixteenthNoteClicked, true);
            app.SixteenthNote.Icon = '16th.png';
            app.SixteenthNote.Separator = 'on';
            app.EigthNote = uipushtool(app.Toolbar);
            app.EigthNote.ClickedCallback = createCallbackFcn(app, @EigthNoteClicked, true);
            app.EigthNote.Icon = '8th.png';
            app.EigthNote.Separator = 'on';
            app.QuarterNote = uipushtool(app.Toolbar);
            app.QuarterNote.ClickedCallback = createCallbackFcn(app, @QuarterNoteClicked, true);
            app.QuarterNote.Icon = 'quarter.png';
            app.QuarterNote.Separator = 'on';
            app.HalfNote = uipushtool(app.Toolbar);
            app.HalfNote.ClickedCallback = createCallbackFcn(app, @HalfNoteClicked, true);
            app.HalfNote.Icon = 'half.png';
            app.HalfNote.Separator = 'on';
            app.DottedQuarterNote = uipushtool(app.Toolbar);
            app.DottedQuarterNote.ClickedCallback = createCallbackFcn(app, @DottedQuarterNoteClicked, true);
            app.DottedQuarterNote.Icon = 'DottedQ.png';
            app.DottedQuarterNote.Separator = 'on';
            app.DottedHalfNote = uipushtool(app.Toolbar);
            app.DottedHalfNote.ClickedCallback = createCallbackFcn(app, @DottedHalfNoteClicked, true);
            app.DottedHalfNote.Icon = 'DH.png';
            app.DottedHalfNote.Separator = 'on';
            app.Whole = uipushtool(app.Toolbar);
            app.Whole.ClickedCallback = createCallbackFcn(app, @WholeClicked, true);
            app.Whole.Icon = 'WholeNote.png';
            app.Whole.Separator = 'on';
            app.IsSharp = uipushtool(app.Toolbar);
            app.IsSharp.ClickedCallback = createCallbackFcn(app, @IsSharpClicked, true);
            app.IsSharp.Icon = 'sharp.png';
            app.IsSharp.Separator = 'on';
            app.IsFlat = uipushtool(app.Toolbar);
            app.IsFlat.ClickedCallback = createCallbackFcn(app, @IsFlatClicked, true);
            app.IsFlat.Icon = 'flat.png';
            app.IsFlat.Separator = 'on';
            app.IsRest = uipushtool(app.Toolbar);
            app.IsRest.ClickedCallback = createCallbackFcn(app, @IsRestClicked, true);
            app.IsRest.Icon = 'quarterRest.png';
            app.IsRest.Separator = 'on';
            app.TempoSliderLabel = uilabel(app.UIFigure);
            app.TempoSliderLabel.Text = 'Tempo';
            app.TempoSlider = uislider(app.UIFigure);
            app.TempoSlider.Limits = [0 120];
            app.TempoSlider.ValueChangedFcn = createCallbackFcn(app, @TempoSliderValueChanged, true);
            app.TempoSlider.Value = 60;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1000];
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabProFinal

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end